classdef Cavo_coassiale < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CaricoDropDownLabel            matlab.ui.control.Label
        CaricoDropDown                 matlab.ui.control.DropDown
        VinDropDownLabel               matlab.ui.control.Label
        VinDropDown                    matlab.ui.control.DropDown
        adattaCheckBox                 matlab.ui.control.CheckBox
        ValoreEditFieldLabel           matlab.ui.control.Label
        ValoreEditField                matlab.ui.control.NumericEditField
        StartButton                    matlab.ui.control.StateButton
        SimulazioneCavoCoassialeLabel  matlab.ui.control.Label
        Tensione                       matlab.ui.control.UIAxes
        Corrente                       matlab.ui.control.UIAxes
        Vin_grafico                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        dz % infinitesima parte di Z
        v % velocita nella linea
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Variabili
            a = 2.8;
            b = 10 ;
            Len = 3;
            epsr = 2.3;
            
            %Costanti
            epsylon0 = 8.85e-12;
            mu0 = 4 * pi * 1e-7;
            
            C = (epsylon0 * epsr * 2 * pi)/log(b/a); % [Fm]
            L = mu0 / (2 * pi) * log(b/a); % [H/m] 
            v = 1/sqrt(L*C);
            app.v =v;
            fmax =1.3e9; 
            dz = v/ (fmax*50);
            app.dz=dz;
            N = ceil(Len/dz);
            
            
            value = app.VinDropDown.Value;
            T = 50e-9;
            dt = (0.5 *dz/v)/500;
            t0 = T/8; 
            deltaT = 300e-12; 
            if value == 'Gaussiana'
                Vin = @(t) normpdf(t,t0,deltaT);
                
            else
                Vin = @(t) sin(t*2*pi*1.4e9);
            end
            fplot(app.Vin_grafico,@(x) Vin(x),[0,T/5])
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            app.StartButton.Text ='Stop';
            disp(class(app.adattaCheckBox.Value));
            %% Variabili
            a = 2.8;    % raggio interno
            b = 10 ;    % raggio esterno   
            Len = 3;    % lunghezza cavo coassiale
            epsr = 2.3; % costante dielettrica relativa
            
            %Costanti
            epsylon0 = 8.85e-12;
            mu0 = 4 * pi * 1e-7;
            
            C = (epsylon0 * epsr * 2 * pi)/log(b/a);    % Capacità per unità di lunghezza
            L = mu0 / (2 * pi) * log(b/a);              % Induttanza per unità di lunghezza
            v = 1/sqrt(L*C);                            % Velocità di propagazione nel cavo
            fmax = 1.3e9; 
            dz = v/ (fmax*50);
            N = ceil(Len/dz);   %% Arrotonda all'intero piu' prossimoo il rapporto Len dz
            
            dz = Len /(N-1); %% Segmentazione della lunghezza della linea
            
            % Time
             T = 50e-9;
             dt = (0.5 *dz/v)/500;
             t0 = T/8; 
             deltaT = 300e-12; 
           
            %% Funzioni d'ingresso
          
           if app.VinDropDown.Value == 'Sinusoide'
               Vin = @(t) sin(t*2*pi*1.4e9);
           else
               Vin = @(t) normpdf(t,t0,deltaT);
           end
            
            %% Carico
            R_load = 0;
            C_load = 0;
            I_load = 0;
            
            %% Selezione del carico
            switch app.CaricoDropDown.Value
                case 'Resistenza'
                    if app.adattaCheckBox.Value 
                        disp('adatta')
                        R_load = sqrt(L/C);
                    else
                        R_load = app.ValoreEditField.Value;
                    end
                case 'Induttore'
                    I_load = app.ValoreEditField.Value;
                case 'Consensatore'
                    C_load = app.ValoreEditField.Value;
            end
           
            if app.adattaCheckBox.Value
                app.ValoreEditField.Value = R_load;
            end
            
            %% Inizializzazioni
            Z = linspace(0,Len,N);  % Segmenti di percorso
            V = zeros(1,N); %% Inizializzo la tensione
            I = zeros(1,N); %% Inizializzo la corrente


            %% Getting Values
            Vin_choose = app.VinDropDown.Value;
            disp(Vin_choose);
            Carico_choose = app.CaricoDropDown.Value;
            disp(Carico_choose);
            Carico_val = app.ValoreEditField.Value;
            disp(Carico_val);
            
            %% Loop
            tmp = 0;
            i = 0;
            for t = 0:dt:T
                if app.StartButton.Value
                   i=i+1;
                   
                   V(1) = Vin(t);
                   
                   %%calcolo condizioni di contorno in base al carico
                   switch app.CaricoDropDown       
                       case 'Resistenza' %% Resistenza a carico
                             I(end) = V(end)/R_load; %% Condizione di contorno della resistenza
                       case 'Conduttore' %% Condensatore a carico
                            I(end) =I(end-1);
                            contorno = tmp + I(end) /C_load * delta_t;   % Condizione di contorno del condensatore
                            V(end) = contorno;
                            tmp = contorno;
                       case 'Induttore' % Induttore a carico
                            I(end) = I(end)+ V(end)/L_load*delta_t;
                   end
        
                    dv_dz = diff(V) / dz;
                    di_dz = diff(I) / dz;
                    
                    I(1:end-1) = I(1:end-1) - (dv_dz / L) * dt;
                    V(2:end) = V(2:end) - (di_dz / C) * dt;
                    
                    
                    %% render
                   if mod(i,10000) == 0
                        plot(app.Tensione,Z, V,'b'); % stampa grafico tensione
                        %legend('V (t)');
                        plot(app.Corrente,Z(1:end-1) + (Z(2) - Z(1))*0.5, I(1:end-1),'r'); % stampa grafico corrente
                        %legend('I (T)');                  
                        drawnow();
                   end  
                else
                    cla(app.Corrente);
                    cla(app.Tensione);
                    
                    break;
                end
                app.StartButton.Text = 'Start';
            end
           
                    
        end

        % Value changed function: VinDropDown
        function VinDropDownValueChanged(app, event)
            value = app.VinDropDown.Value;
            T = 50e-9;
            dt = (0.5 *app.dz/app.v)/500;
            t0 = T/8; 
            deltaT = 300e-12; 
            if value == 'Gaussiana'
                Vin = @(t) normpdf(t,t0,deltaT);
                
            else
                Vin = @(t) sin(t*2*pi*1.4e9);
            end
            fplot(app.Vin_grafico,@(x) Vin(x),[0,T/5])
        end

        % Value changed function: CaricoDropDown
        function CaricoDropDownValueChanged(app, event)
            value = app.CaricoDropDown.Value;
            disp(value);
            disp(size(value))
            if strcmp(app.CaricoDropDown.Value,'Resistenza')
                app.adattaCheckBox.Enable= true;
            else
                app.adattaCheckBox.Enable= false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CaricoDropDownLabel = uilabel(app.UIFigure);
            app.CaricoDropDownLabel.Text = 'Carico';
            app.CaricoDropDown = uidropdown(app.UIFigure);
            app.CaricoDropDown.Items = {'Resistenza', 'Conduttore', 'Induttore'};
            app.CaricoDropDown.ValueChangedFcn = createCallbackFcn(app, @CaricoDropDownValueChanged, true);
            app.CaricoDropDown.Value = 'Resistenza';
            app.VinDropDownLabel = uilabel(app.UIFigure);
            app.VinDropDownLabel.Text = 'Vin';
            app.VinDropDown = uidropdown(app.UIFigure);
            app.VinDropDown.Items = {'Gaussiana', 'Sinusoide'};
            app.VinDropDown.ValueChangedFcn = createCallbackFcn(app, @VinDropDownValueChanged, true);
            app.VinDropDown.Value = 'Gaussiana';
            app.adattaCheckBox = uicheckbox(app.UIFigure);
            app.adattaCheckBox.Text = 'adatta';
            app.ValoreEditFieldLabel = uilabel(app.UIFigure);
            app.ValoreEditFieldLabel.Text = 'Valore';
            app.ValoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.SimulazioneCavoCoassialeLabel = uilabel(app.UIFigure);
            app.SimulazioneCavoCoassialeLabel.Text = 'Simulazione Cavo Coassiale';
            app.Tensione = uiaxes(app.UIFigure);
            title(app.Tensione, 'Tensione ')
            xlabel(app.Tensione, 'Spazio [m]')
            ylabel(app.Tensione, 'Volt [V]')
            zlabel(app.Tensione, 'Z')
            app.Corrente = uiaxes(app.UIFigure);
            title(app.Corrente, 'Corrente')
            xlabel(app.Corrente, 'Spazio [m]')
            ylabel(app.Corrente, 'Ampere [A]')
            zlabel(app.Corrente, 'Z')
            app.Vin_grafico = uiaxes(app.UIFigure);
            title(app.Vin_grafico, 'Vin')
            xlabel(app.Vin_grafico, 'tempo [s]')
            ylabel(app.Vin_grafico, 'Volt [V]')
            zlabel(app.Vin_grafico, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cavo_coassiale

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end